classdef firstTrialGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CubeSatGroundStationUIFigure  matlab.ui.Figure
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        GroundstationforADCSinthecubesatLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        SpeedControlTab               matlab.ui.container.Tab
        ConnectionStatusStandByLamp   matlab.ui.control.Lamp
        ConnectionStatusStandByLampLabel  matlab.ui.control.Label
        ConnectBTButton               matlab.ui.control.Button
        BluetoothIDsDropDown          matlab.ui.control.DropDown
        BluetoothIDsDropDownLabel     matlab.ui.control.Label
        VerificationPanel             matlab.ui.container.Panel
        LiveLamp                      matlab.ui.control.Lamp
        LiveLampLabel                 matlab.ui.control.Label
        speedGraph                    matlab.ui.control.UIAxes
        ControlPanel                  matlab.ui.container.Panel
        ControlaxisDropDown           matlab.ui.control.DropDown
        ControlaxisDropDownLabel      matlab.ui.control.Label
        DirectionButtonGroup          matlab.ui.container.ButtonGroup
        CCWButton                     matlab.ui.control.RadioButton
        CWButton                      matlab.ui.control.RadioButton
        SpeedValueLabel               matlab.ui.control.Label
        SendcmdButton                 matlab.ui.control.Button
        SpeedControllerKnob           matlab.ui.control.Knob
        SpeedControllerKnobLabel      matlab.ui.control.Label
        ZewailCityTeamLabel           matlab.ui.control.Label
        AngleControlTab               matlab.ui.container.Tab
        ControlPanel_2                matlab.ui.container.Panel
        SendcmdButton_2               matlab.ui.control.Button
        ControlaxisDropDown_2         matlab.ui.control.DropDown
        ControlaxisDropDown_2Label    matlab.ui.control.Label
        AngleKnob                     matlab.ui.control.Knob
        AngleKnobLabel                matlab.ui.control.Label
        DataPanel                     matlab.ui.container.Panel
        LivveeLamp                    matlab.ui.control.Lamp
        LivveeLampLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear all; clc; 
            delete(instrfind)

        end

        % Button down function: speedGraph
        function speedGraphButtonDown(app, event)
                
        end

        % Button pushed function: SendcmdButton
        function SendcmdButtonPushed(app, event)
            
                switch app.ControlaxisDropDown.Value
                    case 'x-axis'
                        if app.ConnectionStatusStandByLampLabel.Text == "Connected"
                            msgbox('x-axis control is not emplemented yet! will work in further versions :D');
                        else
                            msgbox('Please connect to the correct microcontroller!');
                        end
                    case 'y-axis'
                        if app.ConnectionStatusStandByLampLabel.Text == "Connected"
                             msgbox('y-axis control is not emplemented yet! will work in further versions :D');
                             app.StopsimulationButton.Enable = 'off';
                        else
                            msgbox('Please connect to the correct microcontroller!');
                        end
                    case 'z-axis'
                        if app.ConnectionStatusStandByLampLabel.Text == "Connected"
                            app.LiveLamp.Color = 'g';
                            global myDevice
                            app.SpeedValueLabel.Text = num2str("Sending: " + uint8(app.SpeedControllerKnob.Value));
                            %% plotting
                         i = 1;

                        %   Reading
                            while 1  
                                flushinput(myDevice)
                                % Sending Comm flag
                                fwrite(myDevice,1,'int8');
                                writeThis = uint8(app.SpeedControllerKnob.Value);
                                if app.CWButton.Value == 1
                                    CW_flag = 1;
                                    fwrite(myDevice,CW_flag,'int8')
                                elseif app.CCWButton.Value == 1
                                    CCW_flag = 0;
                                    fwrite(myDevice,CCW_flag,'int8')
                                end
                                 
                                fwrite(myDevice,writeThis,'int8');
                                %   Reading
                                readData = fread(myDevice,[1,3],'float') ;
                                yaw(i)   = readData(1);
                                if yaw(i) > 360 || yaw(i) < -360
                                    yaw(i) = rem(yaw(i),360);
                                end
                                angularSpeed(i) = readData(2); 
                                t(i)     = readData(3);
                                fprintf("[Yaw = %f, Angular Speed = %f, Time = %f] \n",yaw(i),angularSpeed(i), t(i))
    
                                %   Plotting 
                               
                                plot(app.speedGraph,t,angularSpeed);
                                drawnow
                                i = i + 1;
                            end
                        else
                            msgbox('Please connect to the correct bluetooth Device!');
                        end
                end
        end

        % Value changed function: BluetoothIDsDropDown
        function BluetoothIDsDropDownValueChanged(app, event)
            btValue = app.BluetoothIDsDropDown.Value;
            
        end

        % Value changed function: SpeedControllerKnob
        function SpeedControllerKnobValueChanged(app, event)
            speedKnobValue = app.SpeedControllerKnob.Value;
            
        end

        % Button pushed function: ConnectBTButton
        function ConnectBTButtonPushed(app, event)
            
            if app.BluetoothIDsDropDown.Value == "'btspp://98d351fda176'"
                
                
                f = waitbar(0,'Please wait ...');
                global myDevice
                myDevice = Bluetooth('btspp://98D351FDA176',1);
                pause(1)
                fopen(myDevice);
                waitbar(.33,f,'Connecting to Bluetooth Device');
                pause(0.5)
                waitbar(.67,f,'Establishing Connection');
                pause(0.5)
                waitbar(1,f,'Finishing');
                pause(0.5)
                close(f)
                msgbox('Connected')
               
                app.ConnectionStatusStandByLampLabel.Text = "Connected";
                app.ConnectionStatusStandByLamp.Color = 'g';              
            else
                msgbox('Please connect to the correct microcontroller!')
                app.ConnectionStatusStandByLampLabel.Text = "Not Connected";
                app.ConnectionStatusStandByLamp.Color = 'r';
            end
        end

        % Value changed function: ControlaxisDropDown
        function ControlaxisDropDownValueChanged(app, event)
            value = app.ControlaxisDropDown.Value;
        
        end

        % Callback function
        function StopsimulationButtonPushed(app, event)
            
        end

        % Callback function
        function StopsimulationButtonValueChanged(app, event)
            value = app.StopsimulationButton.Value;
            if value == 1
                app.StopsimulationButton.Text = 'Start simulation'
            else
                app.StopsimulationButton.Text = 'Stop simulation'
            end
        end

        % Callback function
        function StopMotorButtonPushed(app, event)

        end

        % Button pushed function: SendcmdButton_2
        function SendcmdButton_2Pushed(app, event)
            switch app.ControlaxisDropDown_2.Value
                    case 'x-axis'
                        if app.ConnectionStatusStandByLampLabel.Text == "Connected"
                            msgbox('x-axis control is not emplemented yet! will work in further versions :D');
                        else
                            msgbox('Please connect to the correct microcontroller!');
                        end
                    case 'y-axis'
                        if app.ConnectionStatusStandByLampLabel.Text == "Connected"
                             msgbox('y-axis control is not emplemented yet! will work in further versions :D');
                             app.StopsimulationButton.Enable = 'off';
                        else
                            msgbox('Please connect to the correct microcontroller!');
                        end
                    case 'z-axis'
                        if app.ConnectionStatusStandByLampLabel.Text == "Connected"
                            app.LiveLamp.Color = 'g';
                            global myDevice
                            app.SpeedValueLabel.Text = num2str("Sending: " + uint8(app.SpeedControllerKnob.Value));
                            %% plotting
                         i = 1;

                        %   Reading
                            while 1  
                                flushinput(myDevice)
                                % Sending Comm flag
                                fwrite(myDevice,1,'int8');
                                writeThis = uint8(app.SpeedControllerKnob.Value);
                                writeThis2 = uint8(app.AngleKnob.Value);
                                if app.CWButton.Value == 1
                                    CW_flag = 1;
                                    fwrite(myDevice,CW_flag,'int8')
                                elseif app.CCWButton.Value == 1
                                    CCW_flag = 0;
                                    fwrite(myDevice,CCW_flag,'int8')
                                end
                                 
                                fwrite(myDevice,writeThis,'int8');
                                fwrite(myDevice,writeThis2,'int8');
                                
                                %   Reading
                                readData = fread(myDevice,[1,3],'float') ;
                                yaw(i)   = readData(1);
                                if yaw(i) > 360 || yaw(i) < -360
                                    yaw(i) = rem(yaw(i),360);
                                end
                                angularSpeed(i) = readData(2); 
                                t(i)     = readData(3);
                                fprintf("[Yaw = %f, Angular Speed = %f, Time = %f] \n",yaw(i),angularSpeed(i), t(i))
    
                                %   Plotting 
                               
%                               % plot(app.UIAxes,t,yaw);
                                drawnow
                                i = i + 1;
                            end
                        else
                            msgbox('Please connect to the correct bluetooth Device!');
                        end
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CubeSatGroundStationUIFigure = uifigure('Visible', 'off');
            app.CubeSatGroundStationUIFigure.Name = 'Cube Sat Ground Station';
            app.CubeSatGroundStationUIFigure.Icon = 'logocity.gif';
            app.TabGroup = uitabgroup(app.CubeSatGroundStationUIFigure);
            app.SpeedControlTab = uitab(app.TabGroup);
            app.SpeedControlTab.Title = 'Speed Control';
            app.ZewailCityTeamLabel = uilabel(app.SpeedControlTab);
            app.ZewailCityTeamLabel.Interpreter = 'latex';
            app.ZewailCityTeamLabel.Text = '© 2021 - Zewail City Team';
            app.ControlPanel = uipanel(app.SpeedControlTab);
            app.ControlPanel.Title = 'Control Panel';
            app.SpeedControllerKnobLabel = uilabel(app.ControlPanel);
            app.SpeedControllerKnobLabel.Text = 'Speed Controller';
            app.SpeedControllerKnob = uiknob(app.ControlPanel, 'continuous');
            app.SpeedControllerKnob.Limits = [0 5];
            app.SpeedControllerKnob.ValueChangedFcn = createCallbackFcn(app, @SpeedControllerKnobValueChanged, true);
            app.SendcmdButton = uibutton(app.ControlPanel, 'push');
            app.SendcmdButton.ButtonPushedFcn = createCallbackFcn(app, @SendcmdButtonPushed, true);
            app.SendcmdButton.Text = 'Send cmd';
            app.SpeedValueLabel = uilabel(app.ControlPanel);
            app.SpeedValueLabel.Text = 'Speed Value ';
            app.DirectionButtonGroup = uibuttongroup(app.ControlPanel);
            app.DirectionButtonGroup.Title = 'Direction';
            app.CWButton = uiradiobutton(app.DirectionButtonGroup);
            app.CWButton.Text = 'CW';
            app.CWButton.Value = true;
            app.CCWButton = uiradiobutton(app.DirectionButtonGroup);
            app.CCWButton.Text = 'CCW';
            app.ControlaxisDropDownLabel = uilabel(app.ControlPanel);
            app.ControlaxisDropDownLabel.Text = 'Control axis';
            app.ControlaxisDropDown = uidropdown(app.ControlPanel);
            app.ControlaxisDropDown.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.ControlaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ControlaxisDropDownValueChanged, true);
            app.ControlaxisDropDown.Value = 'x-axis';
            app.VerificationPanel = uipanel(app.SpeedControlTab);
            app.VerificationPanel.Title = 'Verification';
            app.speedGraph = uiaxes(app.VerificationPanel);
            title(app.speedGraph, 'Angular Velocity')
            xlabel(app.speedGraph, 'Time')
            ylabel(app.speedGraph, 'Angular Vel.')
            zlabel(app.speedGraph, 'Z')
            app.speedGraph.ButtonDownFcn = createCallbackFcn(app, @speedGraphButtonDown, true);
            app.LiveLampLabel = uilabel(app.VerificationPanel);
            app.LiveLampLabel.Text = 'Live';
            app.LiveLamp = uilamp(app.VerificationPanel);
            app.BluetoothIDsDropDownLabel = uilabel(app.SpeedControlTab);
            app.BluetoothIDsDropDownLabel.Text = 'Bluetooth IDs';
            app.BluetoothIDsDropDown = uidropdown(app.SpeedControlTab);
            app.BluetoothIDsDropDown.Items = {'''btspp://98d351fda176''', '''btspp://34DE1AC3B652'''};
            app.BluetoothIDsDropDown.ValueChangedFcn = createCallbackFcn(app, @BluetoothIDsDropDownValueChanged, true);
            app.BluetoothIDsDropDown.Value = '''btspp://98d351fda176''';
            app.ConnectBTButton = uibutton(app.SpeedControlTab, 'push');
            app.ConnectBTButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectBTButtonPushed, true);
            app.ConnectBTButton.Text = 'Connect BT';
            app.ConnectionStatusStandByLampLabel = uilabel(app.SpeedControlTab);
            app.ConnectionStatusStandByLampLabel.Text = 'Connection Status / Stand By';
            app.ConnectionStatusStandByLamp = uilamp(app.SpeedControlTab);
            app.AngleControlTab = uitab(app.TabGroup);
            app.AngleControlTab.Title = 'Angle Control';
            app.DataPanel = uipanel(app.AngleControlTab);
            app.DataPanel.Title = 'Data';
            app.UIAxes = uiaxes(app.DataPanel);
            title(app.UIAxes, 'Angle Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Angle')
            zlabel(app.UIAxes, 'Z')
            app.LivveeLampLabel = uilabel(app.DataPanel);
            app.LivveeLampLabel.Text = 'Livvee';
            app.LivveeLamp = uilamp(app.DataPanel);
            app.ControlPanel_2 = uipanel(app.AngleControlTab);
            app.ControlPanel_2.Title = 'Control';
            app.AngleKnobLabel = uilabel(app.ControlPanel_2);
            app.AngleKnobLabel.Text = 'Angle';
            app.AngleKnob = uiknob(app.ControlPanel_2, 'continuous');
            app.AngleKnob.Limits = [0 360];
            app.AngleKnob.Value = 30;
            app.ControlaxisDropDown_2Label = uilabel(app.ControlPanel_2);
            app.ControlaxisDropDown_2Label.Text = 'Control axis';
            app.ControlaxisDropDown_2 = uidropdown(app.ControlPanel_2);
            app.ControlaxisDropDown_2.Items = {'x-axis', 'y-axis', 'z-axis'};
            app.ControlaxisDropDown_2.Value = 'x-axis';
            app.SendcmdButton_2 = uibutton(app.ControlPanel_2, 'push');
            app.SendcmdButton_2.ButtonPushedFcn = createCallbackFcn(app, @SendcmdButton_2Pushed, true);
            app.SendcmdButton_2.Text = 'Send cmd';
            app.GroundstationforADCSinthecubesatLabel = uilabel(app.CubeSatGroundStationUIFigure);
            app.GroundstationforADCSinthecubesatLabel.Interpreter = 'latex';
            app.GroundstationforADCSinthecubesatLabel.Text = 'Ground station for ADCS in the cube sat.';
            app.Image = uiimage(app.CubeSatGroundStationUIFigure);
            app.Image.ImageSource = 'logocity.gif';
            app.Image2 = uiimage(app.CubeSatGroundStationUIFigure);
            app.Image2.ImageSource = 'EGSA_Logo.png';
            % Show the figure after all components are created
            app.CubeSatGroundStationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = firstTrialGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CubeSatGroundStationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CubeSatGroundStationUIFigure)
        end
    end
end